classdef DataExport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FilePathPanel          matlab.ui.container.Panel
        DataPathButton         matlab.ui.control.Button
        LabelEditField         matlab.ui.control.Label
        DataPath               matlab.ui.control.EditField
        ExportPathButton       matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        ExportPath             matlab.ui.control.EditField
        FileNamePanel          matlab.ui.container.Panel
        FileListBox            matlab.ui.control.ListBox
        FilterLabel            matlab.ui.control.Label
        FileNameTemplate       matlab.ui.control.EditField
        LabelDropDown          matlab.ui.control.Label
        FileDateSortDropDown   matlab.ui.control.DropDown
        FileRefreshButton      matlab.ui.control.Button
        FileExportPanel        matlab.ui.container.Panel
        ExportButton           matlab.ui.control.Button
        LabelDropDown2         matlab.ui.control.Label
        FileSourceDropDown     matlab.ui.control.DropDown
        LabelLamp              matlab.ui.control.Label
        StatusLamp             matlab.ui.control.Lamp
        Parallel               matlab.ui.control.CheckBox
        ErrorFile              matlab.ui.control.ListBox
        RefreshPeriodsecLabel  matlab.ui.control.Label
        refreshperiod          matlab.ui.control.NumericEditField
        autoexport             matlab.ui.control.CheckBox
        CallbackDropDownLabel  matlab.ui.control.Label
        callback               matlab.ui.control.DropDown
        argsEditFieldLabel     matlab.ui.control.Label
        callbackargs           matlab.ui.control.EditField
    end

    
    properties (Access = public)
        DataFileFolder % dir of files
        refreshtimer % timer to do periodic refresh callback
    end
    
    methods (Access = public)
        
        function results = ExportData(app,datapath,exportpath,datasource,...
                isparallel,callbackcell,varargin)
            if(exist(exportpath,'dir')~=7)
                mkdir(exportpath);
            end
            results=[];
            if ~isempty(datapath)
                app.ErrorFile.Items={};
                
                app.StatusLamp.Color = [0.12,0.85,0.12];
                app.LabelLamp.Text = 'Running';
                results = NeuroAnalysis.Base.EvalFun('NeuroAnalysis.IO.Export',...
                    [{datapath,exportpath,datasource,isparallel,callbackcell},varargin]);
                app.StatusLamp.Color = [0.12,0.15,0.12];
                app.LabelLamp.Text = 'Idle';
                
                status = cellfun(@(x)x.status,results);
                if any(~status)
                    source = cellfun(@(x)x.source,results);
                    app.ErrorFile.Items = source(~status);
                end
            end
        end
        
        function AutoExportData(app)
            
            FileRefreshButtonPushed(app);
            [~,exportfile] = NeuroAnalysis.Base.dirfile(app.ExportPath.Value,...
                '*.mat','Descend');
            dataname = app.FileListBox.Items;
            if ~isempty(dataname)
                [idx,~] = ismember(NeuroAnalysis.Base.filenamenodirext(dataname),...
                    NeuroAnalysis.Base.filenamenodirext(exportfile));
                datapath = cellfun(@(x,y)fullfile(x,y),app.DataFileFolder(~idx)',...
                    dataname(~idx),'UniformOutput',false);
                sourceformat = app.FileSourceDropDown.Value;
                callbackcell = {app.callback.Value, app.callbackargs.Value};
                args = {app,datapath,app.ExportPath.Value,sourceformat,...
                    app.Parallel.Value, callbackcell};
                switch sourceformat
                    case 'OI'
                        args = [args,{false}];
                end
                NeuroAnalysis.Base.EvalFun('ExportData',args);
            end
        end
        
    end

    methods (Access = private)
    
        function Export(app)
            filename = app.FileListBox.Value;
            if ~isempty(filename)
                [idx,~] = ismember(app.FileListBox.Items,filename);
                datapath = cellfun(@(x,y)fullfile(x,y),app.DataFileFolder(idx)',...
                    filename,'UniformOutput',false);
                sourceformat = app.FileSourceDropDown.Value;
                callbackcell = {app.callback.Value, app.callbackargs.Value};
                args = {app,datapath,app.ExportPath.Value,sourceformat,...
                    app.Parallel.Value,callbackcell};
                switch sourceformat
                    case 'OI'
                        args = [args,{true}];
                end
                NeuroAnalysis.Base.EvalFun('ExportData',args);
            end
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t1 = timer;
            t1.ExecutionMode='fixedSpacing';
            t1.TimerFcn = {@(~,~,app)FileRefreshButtonPushed(app),app};
            app.refreshtimer = t1;
            
            preference = NeuroAnalysis.Base.LoadVar('DataExport','DataExportPreference');
            if ~isempty(preference)
                app.DataPath.Value=preference.datapath;
                app.ExportPath.Value=preference.exportpath;
                app.FileNameTemplate.Value=preference.filenametemplate;
                app.FileDateSortDropDown.Value=preference.filedatesort;
                app.FileSourceDropDown.Value=preference.filesource;
                app.Parallel.Value = preference.parallel;
                app.autoexport.Value = preference.autoexport;
                app.refreshperiod.Value = preference.refreshperiod;
                RefreshSelectionChanged(app);
            end
            DataPathChanged(app);
        end

        % Button pushed function: DataPathButton
        function ChooseDataPath(app, event)
            hr = uigetdir('','Choose Data Path');
            if isa(hr,'char')
                app.DataPath.Value = hr;
                DataPathChanged(app);
                app.autoexport.Value = false;
                RefreshSelectionChanged(app);
            end
        end

        % Button pushed function: ExportPathButton
        function ChooseExportPath(app, event)
            hr = uigetdir('','Choose Data Export Path');
            if isa(hr,'char')
                app.ExportPath.Value = hr;
            end
        end

        % Callback function
        function DataPathChanged(app, event)
            [dir,name] = NeuroAnalysis.Base.dirfile(app.DataPath.Value,...
                app.FileNameTemplate.Value,app.FileDateSortDropDown.Value);
            app.FileListBox.Items = name;
            app.DataFileFolder = dir;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            Export(app);
        end

        % Value changed function: FileDateSortDropDown
        function FileDateSortChanged(app, event)
            DataPathChanged(app);
        end

        % Value changed function: FileNameTemplate
        function FileNameTemplateChanged(app, event)
            DataPathChanged(app);
        end

        % Button pushed function: FileRefreshButton
        function FileRefreshButtonPushed(app, event)
            DataPathChanged(app);
        end

        % Value changed function: autoexport
        function RefreshSelectionChanged(app, event)
            stop(app.refreshtimer);
            if app.autoexport.Value
                app.autoexport.FontWeight = 'bold';
                app.refreshtimer.TimerFcn = {@(~,~,app)AutoExportData(app),app};
            else
                app.autoexport.FontWeight='normal';
                app.refreshtimer.TimerFcn = {@(~,~,app)FileRefreshButtonPushed(app),app};
            end
            app.refreshtimer.Period = app.refreshperiod.Value;
            start(app.refreshtimer);
            
        end

        % Close request function: UIFigure
        function closerequestFcn(app, event)
            stop(app.refreshtimer);
            delete(app.refreshtimer);
            
            preference.datapath = app.DataPath.Value;
            preference.exportpath = app.ExportPath.Value;
            preference.filenametemplate = app.FileNameTemplate.Value;
            preference.filedatesort = app.FileDateSortDropDown.Value;
            preference.filesource = app.FileSourceDropDown.Value;
            preference.parallel = app.Parallel.Value;
            preference.autoexport = app.autoexport.Value;
            preference.refreshperiod = app.refreshperiod.Value;
            
            NeuroAnalysis.Base.SaveVar('DataExport',preference,'DataExportPreference');
            delete(app);
        end

        % Value changed function: DataPath
        function DataPathValueChanged(app, event)
            DataPathChanged(app);
            app.autoexport.Value = false;
            RefreshSelectionChanged(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'DataExport';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closerequestFcn, true);
            app.FilePathPanel = uipanel(app.UIFigure);
            app.FilePathPanel.Title = 'File Path';
            app.DataPathButton = uibutton(app.FilePathPanel, 'push');
            app.DataPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataPath, true);
            app.DataPathButton.Text = '...';
            app.LabelEditField = uilabel(app.FilePathPanel);
            app.LabelEditField.Text = 'Data';
            app.DataPath = uieditfield(app.FilePathPanel, 'text');
            app.DataPath.ValueChangedFcn = createCallbackFcn(app, @DataPathValueChanged, true);
            app.ExportPathButton = uibutton(app.FilePathPanel, 'push');
            app.ExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseExportPath, true);
            app.ExportPathButton.Text = '...';
            app.Label = uilabel(app.FilePathPanel);
            app.Label.Text = 'Export';
            app.ExportPath = uieditfield(app.FilePathPanel, 'text');
            app.FileNamePanel = uipanel(app.UIFigure);
            app.FileNamePanel.Title = 'File Name';
            app.FileListBox = uilistbox(app.FileNamePanel);
            app.FileListBox.Items = {};
            app.FileListBox.Multiselect = 'on';
            app.FileListBox.Value = {};
            app.FilterLabel = uilabel(app.FileNamePanel);
            app.FilterLabel.Text = 'Filter';
            app.FileNameTemplate = uieditfield(app.FileNamePanel, 'text');
            app.FileNameTemplate.ValueChangedFcn = createCallbackFcn(app, @FileNameTemplateChanged, true);
            app.LabelDropDown = uilabel(app.FileNamePanel);
            app.LabelDropDown.Text = 'Modify Date';
            app.FileDateSortDropDown = uidropdown(app.FileNamePanel);
            app.FileDateSortDropDown.Items = {'Descend', 'Ascend'};
            app.FileDateSortDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDateSortChanged, true);
            app.FileDateSortDropDown.Value = 'Descend';
            app.FileRefreshButton = uibutton(app.FileNamePanel, 'push');
            app.FileRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @FileRefreshButtonPushed, true);
            app.FileRefreshButton.Icon = 'noun_refresh_cc.png';
            app.FileRefreshButton.Text = '';
            app.FileExportPanel = uipanel(app.UIFigure);
            app.FileExportPanel.Title = 'File Export';
            app.ExportButton = uibutton(app.FileExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.LabelDropDown2 = uilabel(app.FileExportPanel);
            app.LabelDropDown2.Text = 'Source';
            app.FileSourceDropDown = uidropdown(app.FileExportPanel);
            app.FileSourceDropDown.Items = {'Ripple', 'OI', 'Unknown'};
            app.FileSourceDropDown.Value = 'Ripple';
            app.LabelLamp = uilabel(app.FileExportPanel);
            app.LabelLamp.Text = 'Idle';
            app.StatusLamp = uilamp(app.FileExportPanel);
            app.Parallel = uicheckbox(app.FileExportPanel);
            app.Parallel.Text = 'Parallel Export';
            app.Parallel.Value = true;
            app.ErrorFile = uilistbox(app.FileExportPanel);
            app.ErrorFile.Items = {};
            app.ErrorFile.Multiselect = 'on';
            app.ErrorFile.Value = {};
            app.RefreshPeriodsecLabel = uilabel(app.FileExportPanel);
            app.RefreshPeriodsecLabel.Text = 'RefreshPeriod(sec)';
            app.refreshperiod = uieditfield(app.FileExportPanel, 'numeric');
            app.refreshperiod.Limits = [1 89400];
            app.refreshperiod.Value = 10;
            app.autoexport = uicheckbox(app.FileExportPanel);
            app.autoexport.ValueChangedFcn = createCallbackFcn(app, @RefreshSelectionChanged, true);
            app.autoexport.Text = 'Auto Export';
            app.CallbackDropDownLabel = uilabel(app.FileExportPanel);
            app.CallbackDropDownLabel.Text = 'Callback';
            app.callback = uidropdown(app.FileExportPanel);
            app.callback.Items = {'', 'recalculateSingle'};
            app.callback.Value = '';
            app.argsEditFieldLabel = uilabel(app.FileExportPanel);
            app.argsEditFieldLabel.Text = 'args';
            app.callbackargs = uieditfield(app.FileExportPanel, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataExport

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end